classdef BLWremoval < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        FilterAnalysisLabel         matlab.ui.control.Label
        PhaseButton                 matlab.ui.control.Button
        MagnitudeButton             matlab.ui.control.Button
        FilterSelectionButtonGroup  matlab.ui.container.ButtonGroup
        ellipButton                 matlab.ui.control.RadioButton
        cheby2Button                matlab.ui.control.RadioButton
        cheby1Button                matlab.ui.control.RadioButton
        ButterButton                matlab.ui.control.RadioButton
        UIAxes4_2                   matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        CenterPanel                 matlab.ui.container.Panel
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        FilterApplyLabel            matlab.ui.control.Label
        OrderEditField              matlab.ui.control.EditField
        OrderEditFieldLabel         matlab.ui.control.Label
        NoiseremovalButton          matlab.ui.control.Button
        BaselineRemovalButton       matlab.ui.control.Button
        Button                      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        data               % ecg signal
        filtered_data      % filtered ecg
        Wp = [6 40]/100;   % passband
        Ws = [1 45]/100;   % stopband
        Rp = 1;            %passband ripple
        Rs = 40;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [fileName, ~] = uigetfile({'*.dat'}, 'Select a data file');
            fid = fopen(fileName, 'r');
            app.data = fread(fid, Inf, 'int16');
            fclose(fid);
            fs = 250;
            beat_start = 1900; % Starting index of beat in signal  
            beat_end = 2600;   % Ending index of beat in signal
            t = (beat_start:beat_end)/fs; % Time vector for beat
            beat = app.data(beat_start:beat_end);
            plot(app.UIAxes, beat,'g');
            xlabel(app.UIAxes, 'Samples');
            ylabel(app.UIAxes, 'ECG Voltage (mV)');
            title(app.UIAxes, 'Loaded ECG Data');
        end

        % Button pushed function: BaselineRemovalButton
        function BaselineRemovalButtonPushed(app, event)
            filterType= app.FilterSelectionButtonGroup.SelectedObject.Text;

            Fs=250;
            cutoff_freq = 0.5; % Hz
            switch filterType
                case 'Butter'
                    [b, a] = butter(27, 0.5, 'high');
                case 'cheby1'

                    [b, a] = cheby1(9, 0.5, 0.5, 'high');               
                case 'cheby2'
                    [b, a] = cheby2(10, 0.5, 0.5, 'high');
                case 'ellip'
                    [b, a] = ellip(5, cutoff_freq/(Fs/2), 'high'); 
                otherwise
                    error('Unknown filter type selected');
            end

            app.filtered_data = filtfilt(b, a, app.data);
            xlim(app.UIAxes_2, [0 700]);
            plot(app.UIAxes_2,app.filtered_data)
            xlabel(app.UIAxes_2, 'Samples');
            ylabel(app.UIAxes_2, 'ECG Voltage (mV)');
            title(app.UIAxes_2, 'BL wnader removed');

        end

        % Button pushed function: NoiseremovalButton
        function NoiseremovalButtonPushed(app, event)
            filterType= app.FilterSelectionButtonGroup.SelectedObject.Text;

            Fs=250;
            switch filterType
                case 'Butter'
                    [b, a] = butter(5, [6, 40]/(Fs/2), 'bandpass');
                case 'cheby1'

                    [b, a] = cheby1(10,1, [6, 40]/(Fs/2), 'bandpass');

                case 'cheby2'
                    [b, a] = cheby2(10, 40, [6, 40]/(Fs/2), 'bandpass');

                case 'ellip'
                    [b, a] = ellip(5, 1, 40, [6, 40]/(Fs/2), 'bandpass');

                otherwise
                    error('Unknown filter type selected');
            end
            
            app.filtered_data = filtfilt(b, a, app.data);
            normalized_signal = (app.filtered_data - min(app.filtered_data)) / (max(app.filtered_data) - min(app.filtered_data)) * 2 - 1;
            xlim(app.UIAxes2,[0,2500])
            plot(app.UIAxes2,normalized_signal,'r')
            xlabel(app.UIAxes2, 'Samples');
            ylabel(app.UIAxes2, 'ECG Voltage (mV)');
            title(app.UIAxes2, 'noise removed');

        end

        % Button pushed function: MagnitudeButton
        function MagnitudeButtonPushed(app, event)
            FilterType= app.FilterSelectionButtonGroup.SelectedObject.Text
            switch FilterType
                case 'Butter'
                   [n, Wn] = buttord(app.Wp, app.Ws, app.Rp, app.Rs);
                   app.OrderEditField.Value=num2str(n);
                   [z, p, k] = butter(n, Wn);
                   [b, a] = zp2tf(z, p, k);
                   [H, f] = freqz(b, a, 250)
                   magnitude = abs(H);
                   plot(app.UIAxes4, f, magnitude,Color='magenta');
                   title(app.UIAxes4, 'Magnitude Response of Butterworth Filter');
                   xlabel(app.UIAxes4, 'Frequency (Hz)');
                   ylabel(app.UIAxes4, 'Magnitude');
                   grid(app.UIAxes4, 'on');
                   xlim(app.UIAxes4, [min(f), max(f)]);
                case 'cheby1'
                   [n,Wn]=cheb1ord(app.Wp,app.Ws,app.Rp,app.Rs);
                   app.OrderEditField.Value=num2str(n);
                   [z,p,k] = cheby1(n,app.Rp,app.Wp,'bandpass')
                   [b, a] = zp2tf(z, p, k);
                   [H, f] = freqz(b, a, 250)
                   magnitude = abs(H);
                   plot(app.UIAxes4, f, magnitude,Color='magenta');
                   title(app.UIAxes4, 'Magnitude Response of Butterworth Filter');
                   xlabel(app.UIAxes4, 'Frequency (Hz)');
                   ylabel(app.UIAxes4, 'Magnitude');
                   grid(app.UIAxes4, 'on');
                case 'cheby2'
                    [n,Wn]=cheb2ord(app.Wp,app.Ws,app.Rp,app.Rs);
                    app.OrderEditField.Value=num2str(n);
                    [z,p,k] = cheby2(n,app.Rp,app.Wp,'bandpass')
                    [b, a] = zp2tf(z, p, k);
                    [H, f] = freqz(b, a, 250)
                    magnitude = abs(H);
                    plot(app.UIAxes4, f, magnitude,Color='magenta');
                    title(app.UIAxes4, 'Magnitude Response of Butterworth Filter');
                    xlabel(app.UIAxes4, 'Frequency (Hz)');
                    ylabel(app.UIAxes4, 'Magnitude');
                    grid(app.UIAxes4, 'on');
                case 'ellip'
                    [n,Wn] = ellipord(app.Wp,app.Ws,app.Rp,app.Rs)
                    [z,p,k] = ellip(n,app.Rp,app.Rs,app.Wp); 
                    app.OrderEditField.Value=num2str(n);
                    [b, a] = zp2tf(z, p, k);
                    [H, f] = freqz(b, a, 250)
                    magnitude = abs(H);
                    plot(app.UIAxes4, f, magnitude,Color='magenta');
                    title(app.UIAxes4, 'Magnitude Response of Butterworth Filter');
                    xlabel(app.UIAxes4, 'Frequency (Hz)');
                    ylabel(app.UIAxes4, 'Magnitude');
                    grid(app.UIAxes4, 'on');

                    
            end

        end

        % Button pushed function: PhaseButton
        function PhaseButtonPushed(app, event)
            FilterType= app.FilterSelectionButtonGroup.SelectedObject.Text
            switch FilterType
                case 'Butter'
                    [n, Wn] = buttord(app.Wp, app.Ws, app.Rp, app.Rs);
                   app.OrderEditField.Value=num2str(n);
                   [z, p, k] = butter(n, Wn);
                   [b, a] = zp2tf(z, p, k);
                   [H, f] = freqz(b, a, 250)
                   phase = angle(H);
                   phase = rad2deg(phase)
                   plot(app.UIAxes4_2, f, phase);
                   title(app.UIAxes_2, 'Magnitude Response of Butterworth Filter');
                   xlabel(app.UIAxes4_2, 'Frequency (Hz)');
                   ylabel(app.UIAxes4_2, 'Magnitude');
                   grid(app.UIAxes4_2, 'on');
                case 'cheby1'
                    [n, Wn] = cheb1ord(app.Wp, app.Ws, app.Rp, app.Rs);
                    app.OrderEditField.Value=num2str(n);
                    [z, p, k] = cheby1(n, app.Rp, Wn);  
                    [b, a] = zp2tf(z, p, k);
                    [H, f] = freqz(b, a, 250);
                    phase = angle(H);  % Calculate phase response in radians

                    % Convert phase to degrees if desired
                    phase = rad2deg(phase);

                    plot(app.UIAxes4_2, f, phase);
                    title(app.UIAxes4_2, 'Phase Response of Chebyshev Type I Filter');
                    xlabel(app.UIAxes4_2, 'Frequency (Hz)');
                    ylabel(app.UIAxes4_2, 'Phase (Radians)');  
                    grid(app.UIAxes4_2, 'on');
                case 'cheby2'
                    [n, Wn] = cheb2ord(app.Wp, app.Ws, app.Rp, app.Rs);
                    app.OrderEditField.Value=num2str(n);
                    [z, p, k] = cheby2(n, app.Rp, Wn);  
                    [b, a] = zp2tf(z, p, k);
                    [H, f] = freqz(b, a, 250);
                    phase = angle(H);  % Calculate phase response in radians

                    % Convert phase to degrees if desired
                    phase = rad2deg(phase);

                    plot(app.UIAxes4_2, f, phase);
                    title(app.UIAxes4_2, 'Phase Response of Chebyshev Type 2 Filter');
                    xlabel(app.UIAxes4_2, 'Frequency (Hz)');
                    ylabel(app.UIAxes4_2, 'Phase (Radians)');  
                    grid(app.UIAxes4_2, 'on');
                case 'ellip'
                    [n,Wn] = ellipord(app.Wp,app.Ws,app.Rp,app.Rs)
                    [z,p,k] = ellip(n,app.Rp,app.Rs,app.Wp); 
                    app.OrderEditField.Value=num2str(n); 
                    [b, a] = zp2tf(z, p, k);
                    [H, f] = freqz(b, a, 250);
                    phase = angle(H);  % Calculate phase response in radians

                    % Convert phase to degrees if desired
                    phase = rad2deg(phase);

                    plot(app.UIAxes4_2, f, phase);
                    title(app.UIAxes4_2, 'Phase Response of elliptic Filter');
                    xlabel(app.UIAxes4_2, 'Frequency (Hz)');
                    ylabel(app.UIAxes4_2, 'Phase (Radians)');  
                    grid(app.UIAxes4_2, 'on');


            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {399, '1x', 235};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes3 = uiaxes(app.LeftPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.LeftPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTick = [0.2 0.4 0.6 0.8 1];
            app.UIAxes4_2 = uiaxes(app.LeftPanel);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            zlabel(app.UIAxes4_2, 'Z')
            app.FilterSelectionButtonGroup = uibuttongroup(app.LeftPanel);
            app.FilterSelectionButtonGroup.Title = 'Filter Selection';
            app.ButterButton = uiradiobutton(app.FilterSelectionButtonGroup);
            app.ButterButton.Text = 'Butter';
            app.ButterButton.Value = true;
            app.cheby1Button = uiradiobutton(app.FilterSelectionButtonGroup);
            app.cheby1Button.Text = 'cheby1';
            app.cheby2Button = uiradiobutton(app.FilterSelectionButtonGroup);
            app.cheby2Button.Text = 'cheby2';
            app.ellipButton = uiradiobutton(app.FilterSelectionButtonGroup);
            app.ellipButton.Text = 'ellip';
            app.MagnitudeButton = uibutton(app.LeftPanel, 'push');
            app.MagnitudeButton.ButtonPushedFcn = createCallbackFcn(app, @MagnitudeButtonPushed, true);
            app.MagnitudeButton.Text = 'Magnitude';
            app.PhaseButton = uibutton(app.LeftPanel, 'push');
            app.PhaseButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseButtonPushed, true);
            app.PhaseButton.Text = 'Phase';
            app.FilterAnalysisLabel = uilabel(app.LeftPanel);
            app.FilterAnalysisLabel.Text = 'Filter Analysis';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'original signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'vector-upload-icon.jpg');
            app.Button.Text = '';
            app.BaselineRemovalButton = uibutton(app.RightPanel, 'push');
            app.BaselineRemovalButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineRemovalButtonPushed, true);
            app.BaselineRemovalButton.Text = 'Baseline Removal';
            app.NoiseremovalButton = uibutton(app.RightPanel, 'push');
            app.NoiseremovalButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseremovalButtonPushed, true);
            app.NoiseremovalButton.Text = 'Noise removal';
            app.OrderEditFieldLabel = uilabel(app.RightPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.RightPanel, 'text');
            app.FilterApplyLabel = uilabel(app.RightPanel);
            app.FilterApplyLabel.Text = 'Filter Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BLWremoval

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end